classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PressbacktogobacktothemainpagewhatyoudonewillberecordedLabel  matlab.ui.control.Label
        pressokandtheimagewillapplyhereLabel  matlab.ui.control.Label
        inputthefullfilepathforexampleuwapngLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        backButton              matlab.ui.control.Button
        okButton                matlab.ui.control.Button
        FilePathEditField       matlab.ui.control.EditField
        FilePathEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private) 
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            app.Image.ImageSource=app.FilePathEditField.Value;
            updateImg(app.CallingApp, app.Image.ImageSource);
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            % Call main app's public function
            updateImg(app.CallingApp, app.Image.ImageSource);
            
            % Delete the dialog box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            app.Image = uiimage(app.UIFigure);
            app.inputthefullfilepathforexampleuwapngLabel = uilabel(app.UIFigure);
            app.inputthefullfilepathforexampleuwapngLabel.Text = 'input the full file path,for example: ./uwa.png';
            app.pressokandtheimagewillapplyhereLabel = uilabel(app.UIFigure);
            app.pressokandtheimagewillapplyhereLabel.Text = 'press ok and the image will apply here';
            app.PressbacktogobacktothemainpagewhatyoudonewillberecordedLabel = uilabel(app.UIFigure);
            app.PressbacktogobacktothemainpagewhatyoudonewillberecordedLabel.Text = 'press back to go back to the main page, image show on here will be given back to the main page';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end