classdef week4lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HighPassButton           matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        EditFieldSigma           matlab.ui.control.NumericEditField
        EditFieldSize            matlab.ui.control.NumericEditField
        EditFieldHb              matlab.ui.control.NumericEditField
        LowPassButton            matlab.ui.control.Button
        HighBoostButton          matlab.ui.control.Button
        HistogramEqualizeButton  matlab.ui.control.Button
        LoadImageButton          matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    %Chenxin Hu
    %22961779
    properties (Access = private)
        img%store original image
        imgsrc%store original image src
    end


    methods (Access = public)
        
        function updateImg(app,tempImg)%using a function to update result image
            %setting color map/image/axis
            colormap(app.UIAxes,"gray");
            image(app.UIAxes,tempImg);
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %setting default values
            app.EditFieldSize.Value=3;
            app.EditFieldSigma.Value=2;
            app.EditFieldHb.Value=10;
        end

        % Button pushed function: LowPassButton
        function LowPassButtonPushed(app, event)
            %apply gaussian filter using fspecial
            gaussianFilter=fspecial('gaussian',[app.EditFieldSize.Value,app.EditFieldSize.Value],app.EditFieldSigma.Value);
            tempImg=imfilter(app.img,gaussianFilter,'replicate');
            updateImg(app,tempImg)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %load image using uigetfile
            %don't know why when loading, the window will hide from the
            %front of dest
            %and you need to find it on the menu
            [imname,app.imgsrc]=uigetfile;
            app.imgsrc=[app.imgsrc,imname];
            app.Image.ImageSource=app.imgsrc;
            app.img=imread(app.imgsrc);
        end

        % Button pushed function: HistogramEqualizeButton
        function HistogramEqualizeButtonPushed(app, event)
            %using histeq()to histogram equalize the image
            updateImg(app,histeq(app.img));
        end

        % Button pushed function: HighPassButton
        function HighPassButtonPushed(app, event)
            %using laplacian filter for lowwpass image
            %highpass image=original image-lowpass image
            laplacianFilter=fspecial('laplacian',0.9);
            tempImg=imfilter(app.img,laplacianFilter,'replicate');
            updateImg(app,app.img-tempImg);
        end

        % Button pushed function: HighBoostButton
        function HighBoostButtonPushed(app, event)
            %apply gaussianFilter(low pass filter)
            gaussianFilter=fspecial('gaussian',[3,3],2);
            %using formula: 
            %highBoostIm=value*originalImg-lowpassIm
            HbIm=app.EditFieldHb.Value*app.img-imfilter(app.img,gaussianFilter,'replicate');
            updateImg(app,HbIm)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HistogramEqualizeButton = uibutton(app.UIFigure, 'push');
            app.HistogramEqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizeButtonPushed, true);
            app.HistogramEqualizeButton.Text = 'Histogram Equalize';
            app.HighBoostButton = uibutton(app.UIFigure, 'push');
            app.HighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostButtonPushed, true);
            app.HighBoostButton.Text = 'High Boost';
            app.LowPassButton = uibutton(app.UIFigure, 'push');
            app.LowPassButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassButtonPushed, true);
            app.LowPassButton.Text = 'Low Pass';
            app.EditFieldHb = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldSize = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldSigma = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.HighPassButton = uibutton(app.UIFigure, 'push');
            app.HighPassButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassButtonPushed, true);
            app.HighPassButton.Text = 'High Pass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = week4lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end