classdef week5lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DropDown           matlab.ui.control.DropDown
        DetectEdgesButton  matlab.ui.control.Button
        ApproximateradiusforcircledetectionSlider  matlab.ui.control.Slider
        ApproximateradiusforcircledetectionSliderLabel  matlab.ui.control.Label
        ThresholdforedgedetectionSlider  matlab.ui.control.Slider
        ThresholdforedgedetectionSliderLabel  matlab.ui.control.Label
        LoadImageButton    matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    %Chenxin Hu
    %22961779 lab04 week5
    properties (Access = private)
        img%store original image
        imgsrc%store original image src
    end


    methods (Access = public)
        
        function updateResImg(app,tempImg)%using a function to update result image
            %setting color map/image/axis
            colormap(app.UIAxes,"gray");
            imagesc(app.UIAxes,tempImg);
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
        end

        function updateOriImg(app,tempImg)%using a function to update result image
            %setting color map/image/axis
            colormap(app.UIAxes2,"gray");
            imagesc(app.UIAxes2,tempImg);
            axis(app.UIAxes2,"off");
            axis(app.UIAxes2,"equal");
        end
        

        function detectImg(app)% detect image edges
            %turn image to gray
            tempIm=im2gray(app.img);
            %detect edges using methods selected and value of threhold
            edgeIm=edge(tempIm,app.DropDown.Value,app.ThresholdforedgedetectionSlider.Value);     
            updateResImg(app,edgeIm);
        end

        function findCircle(app)
            %detecting edges
            tempIm=im2gray(app.img);
            edgeIm=edge(tempIm,app.DropDown.Value,app.ThresholdforedgedetectionSlider.Value);
            %convert radius to int for circle detection
            input=int8(app.ApproximateradiusforcircledetectionSlider.Value);
            %detect circles in a range(-4 because the input should be positive,and the minius input is 5)
            [centers,radius]=imfindcircles(edgeIm,[input-4,input+15]);
            %draw detect result to the graph
            updateResImg(app,edgeIm);
            viscircles(app.UIAxes,centers,radius,'EdgeColor','b');
            updateOriImg(app,app.img);
            viscircles(app.UIAxes2,centers,radius,'EdgeColor','b');
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %setting default values
            %app.EditFieldSize.Value=3;
            %app.EditFieldSigma.Value=2;
            %app.EditFieldHb.Value=10;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %load image using uigetfile
            %don't know why when loading, the window will hide from the
            %front of dest
            %and you need to find it on the menu
            [imname,app.imgsrc]=uigetfile;
            app.imgsrc=[app.imgsrc,imname];
            app.img=imread(app.imgsrc);
            updateOriImg(app,app.img);
        end

        % Callback function: DetectEdgesButton, DropDown
        function DetectEdgesButtonPushed(app, event)
            findCircle(app);
        end

        % Value changed function: ThresholdforedgedetectionSlider
        function ThresholdforedgedetectionSliderValueChanged(app, event)
            value = app.ThresholdforedgedetectionSlider.Value;
            findCircle(app);
        end

        % Value changed function: ApproximateradiusforcircledetectionSlider
        function ApproximateradiusforcircledetectionSliderValueChanged(app, event)
            value = app.ApproximateradiusforcircledetectionSlider.Value;
            findCircle(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ThresholdforedgedetectionSliderLabel = uilabel(app.UIFigure);
            app.ThresholdforedgedetectionSliderLabel.Text = 'Threshold for edge detection';
            app.ThresholdforedgedetectionSlider = uislider(app.UIFigure);
            app.ThresholdforedgedetectionSlider.Limits = [0 1];
            app.ThresholdforedgedetectionSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdforedgedetectionSliderValueChanged, true);
            app.ApproximateradiusforcircledetectionSliderLabel = uilabel(app.UIFigure);
            app.ApproximateradiusforcircledetectionSliderLabel.Text = 'Approximate radius for circle detection';
            app.ApproximateradiusforcircledetectionSlider = uislider(app.UIFigure);
            app.ApproximateradiusforcircledetectionSlider.Limits = [5 100];
            app.ApproximateradiusforcircledetectionSlider.ValueChangedFcn = createCallbackFcn(app, @ApproximateradiusforcircledetectionSliderValueChanged, true);
            app.ApproximateradiusforcircledetectionSlider.Value = 5;
            app.DetectEdgesButton = uibutton(app.UIFigure, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DetectEdgesButton.Text = 'Detect Edges';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Canny', 'Prewitt', 'Roberts', 'log', 'zerocross', 'Sobel', 'approxcanny'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DropDown.Value = 'Canny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = week5lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end