classdef lab5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LowerthresholdSlider       matlab.ui.control.Slider
        LowerthresholdSliderLabel  matlab.ui.control.Label
        UpperthresholdSlider       matlab.ui.control.Slider
        UpperthresholdSliderLabel  matlab.ui.control.Label
        LoadImageButton            matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    %  Chenxin Hu
    %  22961779 lab05 week6
    %  tried rgb and hsv, find hsv colour space best

    %  HSV approach: check all pixels in hsv image, if h value in range [lowerThreshold, upperThreshold]
    %  set the pixel to black, else set the value to white

    %  RGB approach: check all pixels in rgb image, if r+g+b in range
    %  [lowerThreshold, upperThreshold]*3*255
    %  set the pixel to black, else set the value to white
    properties (Access = private)
        img%  store original image
        imgsrc%  store original image src
        upperThre
        lowerThre
    end


    methods (Access = public)
        
        function updateResImg(app,tempImg)%  using a function to update result image
            %  setting color map/image/axis
            colormap(app.UIAxes,"gray");
            imagesc(app.UIAxes,tempImg);
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
        end

        function updateOriImg(app,tempImg)%  using a function to update result image
            %  setting color map/image/axis
            colormap(app.UIAxes2,"gray");
            imagesc(app.UIAxes2,tempImg);
            axis(app.UIAxes2,"off");
            axis(app.UIAxes2,"equal");
        end
        
        function findSegHSV(app)
            tempIm=app.img;
            %  covert RGB images to HSV
            tempIm=rgb2hsv(tempIm);
            uThre=app.UpperthresholdSlider.Value;
            lThre=app.LowerthresholdSlider.Value;
            [y,x,z]=size(tempIm);
            %  for every pixel in image
            %  if h value in range [upperThreshold, lowerThredshold]
            %  set the pixel black
            %  else set the pixel white
            for i =1:y
                for j=1:x
                    color=tempIm(i,j,1);
                    if color<=uThre&&color>=lThre
                        tempIm(i,j,1)=0;
                        tempIm(i,j,2)=0;
                        tempIm(i,j,3)=0;
                    else
                        tempIm(i,j,1)=1;
                        tempIm(i,j,2)=1;
                        tempIm(i,j,3)=100;
                    end
                end
            end

            updateResImg(app,tempIm)
        end

        function findSegRGB(app)
            tempIm=app.img;
            [x,y,t]=size(tempIm);
            uThre=app.UpperthresholdSlider.Value;
            lThre=app.LowerthresholdSlider.Value;
            for i=1:x
                for j=1:y
                    sum=tempIm(i,j,1)+tempIm(i,j,2)+tempIm(i,j,3);
                    if sum>lThre*255*3 && sum<uThre*255*3
                        tempIm(i,j,1)=0;
                        tempIm(i,j,2)=0;
                        tempIm(i,j,3)=0;
                    else
                        tempIm(i,j,1)=255;
                        tempIm(i,j,2)=255;
                        tempIm(i,j,3)=255;
                    end
                end
            end
            updateResImg(app,tempIm)
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % default load peppers.png
            app.img=imread("peppers.png");
            updateOriImg(app,app.img);
            % set default value
            app.UpperthresholdSlider.Value=0.64;
            app.LowerthresholdSlider.Value=0.15;
            % result
            findSegHSV(app);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [imname,app.imgsrc]=uigetfile;
            app.imgsrc=[app.imgsrc,imname];
            app.img=imread(app.imgsrc);
            updateOriImg(app,app.img);
        end

        % Value changed function: UpperthresholdSlider
        function UpperthresholdSliderValueChanged(app, event)
            app.upperThre = app.UpperthresholdSlider.Value;
            findSegHSV(app);
        end

        % Value changed function: LowerthresholdSlider
        function LowerthresholdSliderValueChanged(app, event)
            app.lowerThre = app.LowerthresholdSlider.Value;
            findSegHSV(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UpperthresholdSliderLabel = uilabel(app.UIFigure);
            app.UpperthresholdSliderLabel.Text = 'Upper threshold';
            app.UpperthresholdSlider = uislider(app.UIFigure);
            app.UpperthresholdSlider.Limits = [0 1];
            app.UpperthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @UpperthresholdSliderValueChanged, true);
            app.UpperthresholdSlider.Value = 1;
            app.LowerthresholdSliderLabel = uilabel(app.UIFigure);
            app.LowerthresholdSliderLabel.Text = 'Lower threshold';
            app.LowerthresholdSlider = uislider(app.UIFigure);
            app.LowerthresholdSlider.Limits = [0 1];
            app.LowerthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @LowerthresholdSliderValueChanged, true);
            app.LowerthresholdSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end