classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CurrentF1TextArea              matlab.ui.control.TextArea
        CurrentF1TextAreaLabel         matlab.ui.control.Label
        CurrentrecallTextArea          matlab.ui.control.TextArea
        CurrentrecallTextAreaLabel     matlab.ui.control.Label
        CurrentprecisionTextArea       matlab.ui.control.TextArea
        CurrentprecisionTextAreaLabel  matlab.ui.control.Label
        LoadGroundTFileButton          matlab.ui.control.Button
        AutoTrackButton                matlab.ui.control.Button
        NextFrameButton                matlab.ui.control.Button
        LoadImagesButton               matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        totalFrame
        dirPath
        track
        iouData
        num% Description
        gtArray
        decArray
        TPtt = 0;
        FPtt = 0;
        FNtt = 0;
        precision = 0;
        recall = 0;
        F1 = 0;
    end
    
    methods (Access = private)
        function updateResImg(app,tempImg)%  using a function to update result image
            %  setting color map/image/axis
            nTracks = length(app.track);
            colormap(app.UIAxes,"gray");
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
            imagesc(app.UIAxes,tempImg);
            app.UIAxes.NextPlot = 'add';
            colormap(app.UIAxes,"gray");
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
            for i = 1:nTracks
                if(app.track(i).death==0)
                    %debug only
                    %plot(app.UIAxes,app.track(i).x,app.track(i).y,'b*')
                    pos = [app.track(i).lx,app.track(i).ly,app.track(i).w,app.track(i).h];
                    rectangle(app.UIAxes,"Position",pos,'EdgeColor','b');
                    text(app.UIAxes,app.track(i).x,app.track(i).y,num2str(app.track(i).id));
                else
                    %debug only
                    %plot(app.UIAxes,app.track(i).x,app.track(i).y,'r*')
                    pos = [app.track(i).lx,app.track(i).ly,app.track(i).w,app.track(i).h];
                    rectangle(app.UIAxes,"Position",pos,'EdgeColor','r');
                    text(app.UIAxes,app.track(i).x,app.track(i).y,num2str(app.track(i).id));
                end
            end
            app.UIAxes.NextPlot = 'replaceall';
        end

        function updateOriImg(app,tempImg)%  using a function to update result image
            %  setting color map/image/axis
            colormap(app.UIAxes2,"gray");
            imagesc(app.UIAxes2,tempImg);
            axis(app.UIAxes2,"off");
            axis(app.UIAxes2,"equal");
        end

        function updataLinechart(app,num)
            text(app.UIAxes3,(num-1)*10,app.decArray(num-1),num2str(app.decArray(num-1)));
            rectangle(app.UIAxes3,'Position',[(num-1)*10,0,5,app.decArray(num-1)],'EdgeColor','b');
            text(app.UIAxes3,(num-1)*10+5,app.gtArray(num-1),num2str(app.gtArray(num-1)));
            rectangle(app.UIAxes3,'Position',[(num-1)*10+5,0,5,app.gtArray(num-1)],'EdgeColor','r');
            text(app.UIAxes3,(num-1)*10+5,0,["Frame",num2str(num-1)]);
            axis(app.UIAxes3,"off");
        end

        function updatatext(app,TP,FP,FN)
            app.TPtt = app.TPtt + TP;
            app.FPtt = app.FPtt + FP;
            app.FNtt = app.FNtt + FN;
            tptt = app.TPtt;
            fptt = app.FPtt;
            fntt = app.FNtt;
                
            currprecision = tptt/(tptt+fptt);
            currrecall = tptt/(tptt+fntt);
            currF1 = (currprecision*currrecall)/(currprecision+currrecall);                
            app.CurrentprecisionTextArea.Value = num2str(currprecision);
            app.CurrentrecallTextArea.Value = num2str(currrecall);
            app.CurrentF1TextArea.Value = num2str(currF1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            loader = dataloader();
            app.dirPath=uigetdir;
            dirList=dir(app.dirPath);
            app.totalFrame=length(dirList)-2;
            app.num=1;
            [differedIm,targetIm]=loader.loadDir(app.dirPath,app.num);

            centerLocation=caculateCenter(differedIm);

            app.track=iniTrack(centerLocation);

            updateOriImg(app,targetIm);
            updateResImg(app,targetIm);
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            if app.num<app.totalFrame

                loader = dataloader();

                app.num=app.num+1;

                [differedIm,targetIm]=loader.loadDir(app.dirPath,app.num);
                centerLocation=caculateCenter(differedIm);

                expandIm = expand(targetIm,centerLocation);

                [rec,reccc] = morCues(expandIm);

                app.track=updateTrack(app.track,rec,reccc);

                updateOriImg(app,targetIm);
                updateResImg(app,targetIm);

                [TP,FP,FN,GTcount] = iou(app.iouData,reccc,app.num);

                app.gtArray = [app.gtArray;GTcount];
                app.decArray = [app.decArray;size(reccc,1)];
                updataLinechart(app,app.num);

                updatatext(app,TP,FP,FN);
            end
        end

        % Button pushed function: AutoTrackButton
        function AutoTrackButtonPushed(app, event)
            while app.num<app.totalFrame
                loader = dataloader();
                app.num=app.num+1;
                [differedIm,targetIm]=loader.loadDir(app.dirPath,app.num);

                centerLocation=caculateCenter(differedIm);

                expandIm = expand(targetIm,centerLocation);

                [rec,reccc] = morCues(expandIm);

                app.track=updateTrack(app.track,rec,reccc);
                
                tracks=app.track;
                nTracks = length(tracks);
                %figure(5),imshow(targetIm);
                %hold on

                updateOriImg(app,targetIm);
                updateResImg(app,targetIm);
                [TP,FP,FN,GTcount] = iou(app.iouData,rec,app.num);

                app.gtArray = [app.gtArray;GTcount];
                app.decArray = [app.decArray;size(reccc,1)];
                updataLinechart(app,app.num)

                updatatext(app,TP,FP,FN);
                pause(0.1);
            end
        end

        % Button pushed function: LoadGroundTFileButton
        function LoadGroundTFileButtonPushed(app, event)
            loader = dataloader();
            [filename,pathname] = uigetfile({'*.txt'});
            app.iouData = loader.loadGT(filename, pathname);
        end

        % Value changed function: CurrentF1TextArea
        function CurrentF1TextAreaValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'groundtrue No. vs dectected No.')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadImagesButton = uibutton(app.GridLayout, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.NextFrameButton = uibutton(app.GridLayout, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.AutoTrackButton = uibutton(app.GridLayout, 'push');
            app.AutoTrackButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTrackButtonPushed, true);
            app.AutoTrackButton.Text = 'Auto Track';
            app.LoadGroundTFileButton = uibutton(app.GridLayout, 'push');
            app.LoadGroundTFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTFileButtonPushed, true);
            app.LoadGroundTFileButton.Text = 'Load GroundTFile';
            app.CurrentprecisionTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentprecisionTextAreaLabel.Text = 'Current precision';
            app.CurrentprecisionTextArea = uitextarea(app.GridLayout);
            app.CurrentrecallTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentrecallTextAreaLabel.Text = 'Current recall';
            app.CurrentrecallTextArea = uitextarea(app.GridLayout);
            app.CurrentF1TextAreaLabel = uilabel(app.GridLayout);
            app.CurrentF1TextAreaLabel.Text = 'Current F1';
            app.CurrentF1TextArea = uitextarea(app.GridLayout);
            app.CurrentF1TextArea.ValueChangedFcn = createCallbackFcn(app, @CurrentF1TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end