classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AutoTrackButton   matlab.ui.control.Button
        NextFrameButton   matlab.ui.control.Button
        LoadImagesButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        totalFrame
        dirPath
        track
        num% Description
    end
    
    methods (Access = private)
        function updateResImg(app,tempImg)%  using a function to update result image
            %  setting color map/image/axis
            nTracks = length(app.track);
            colormap(app.UIAxes,"gray");
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
            imagesc(app.UIAxes,tempImg);
            app.UIAxes.NextPlot = 'add';
            colormap(app.UIAxes,"gray");
            axis(app.UIAxes,"off");
            axis(app.UIAxes,"equal");
            for i = 1:nTracks
                if(app.track(i).death==0)
                plot(app.UIAxes,app.track(i).x,app.track(i).y,'b*')
                text(app.UIAxes,app.track(i).x,app.track(i).y,num2str(app.track(i).id));
                else
                plot(app.UIAxes,app.track(i).x,app.track(i).y,'r*')
                text(app.UIAxes,app.track(i).x,app.track(i).y,num2str(app.track(i).id));
                end
            end
            app.UIAxes.NextPlot = 'replaceall';
        end

        function updateOriImg(app,tempImg)%  using a function to update result image
            %  setting color map/image/axis
            colormap(app.UIAxes2,"gray");
            imagesc(app.UIAxes2,tempImg);
            axis(app.UIAxes2,"off");
            axis(app.UIAxes2,"equal");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            app.dirPath=uigetdir;
            dirList=dir(app.dirPath);
            app.totalFrame=length(dirList)-2;
            app.num=1;
            [differedIm,targetIm]=loadDir(app.dirPath,app.num);
            centerLocation=caculateCenter(differedIm);
            app.track=iniTrack(centerLocation);
            updateOriImg(app,targetIm);
            updateResImg(app,targetIm);
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            if app.num<app.totalFrame
                app.num=app.num+1;
                [differedIm,targetIm]=loadDir(app.dirPath,app.num);
                centerLocation=caculateCenter(differedIm);
                app.track=updateTrack(app.track,centerLocation);
                updateOriImg(app,targetIm);
                updateResImg(app,targetIm);
            end
        end

        % Button pushed function: AutoTrackButton
        function AutoTrackButtonPushed(app, event)
            while app.num<app.totalFrame
            app.num=app.num+1;
            [differedIm,targetIm]=loadDir(app.dirPath,app.num);
            centerLocation=caculateCenter(differedIm);
            app.track=updateTrack(app.track,centerLocation);
            tracks=app.track;
            nTracks = length(tracks);
            figure(5),imshow(targetIm);
            %hold on
            hold on
            for i = 1:nTracks
                if(tracks(i).death==0)
                    plot(tracks(i).x,tracks(i).y,'b*')
                    text(tracks(i).x,tracks(i).y,num2str(tracks(i).id));
                else
                    plot(tracks(i).x,tracks(i).y,'r*')
                    text(tracks(i).x,tracks(i).y,num2str(tracks(i).id));
                end
            end
            hold off
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.AutoTrackButton = uibutton(app.UIFigure, 'push');
            app.AutoTrackButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTrackButtonPushed, true);
            app.AutoTrackButton.Text = 'Auto Track';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end